classdef StarDeltaConverter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Image          matlab.ui.control.Image
        R1Label        matlab.ui.control.Label
        R2Label        matlab.ui.control.Label
        R3Label        matlab.ui.control.Label
        CONVERTButton  matlab.ui.control.Button
        Image2         matlab.ui.control.Image
        R2Label_2      matlab.ui.control.Label
        R1Label_3      matlab.ui.control.Label
        R3Label_2      matlab.ui.control.Label
        A              matlab.ui.control.NumericEditField
        B              matlab.ui.control.NumericEditField
        C              matlab.ui.control.NumericEditField
        R1             matlab.ui.control.NumericEditField
        R2             matlab.ui.control.NumericEditField
        R3             matlab.ui.control.NumericEditField
        Image3         matlab.ui.control.Image
        Image4         matlab.ui.control.Image
        Image5         matlab.ui.control.Image
        RESETButton    matlab.ui.control.Button
        ALabel         matlab.ui.control.Label
        BLabel         matlab.ui.control.Label
        CLabel         matlab.ui.control.Label
        NLabel         matlab.ui.control.Label
        ALabel_2       matlab.ui.control.Label
        BLabel_2       matlab.ui.control.Label
        CLabel_2       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CONVERTButton
        function CONVERTButtonPushed(app, event)
            R1 = app.R1.Value;
            R2 = app.R2.Value;
            R3 = app.R3.Value;
            B = app.B.Value;
            A = app.A.Value;
            C = app.C.Value;
            if((A | B | C) == 0)
                
                numerator = (R1*R2) + (R3*R2) + (R1*R3);
            
                A = numerator/R1; 
                B = numerator/R2; 
                C = numerator/R3; 
                
                app.B.Value = B;
                app.A.Value = A;
                app.C.Value = C;
                
            else
                denominator = A + B + C;
            
                R1 = (B*C)/denominator; 
                R2 = (A*C)/denominator;  
                R3 = (A*B)/denominator; 
                
                app.R1.Value = R1;
                app.R2.Value = R2;
                app.R3.Value = R3;
           
               
            end


        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.R1.Value = 0;
            app.R2.Value = 0;
            app.R3.Value = 0;
            
            app.B.Value = 0;
            app.A.Value = 0;
            app.C.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Screenshot__152_-removebg-preview.png';
            app.R1Label = uilabel(app.UIFigure);
            app.R1Label.Text = 'R1';
            app.R2Label = uilabel(app.UIFigure);
            app.R2Label.Text = 'R2';
            app.R3Label = uilabel(app.UIFigure);
            app.R3Label.Text = 'R3';
            app.CONVERTButton = uibutton(app.UIFigure, 'push');
            app.CONVERTButton.ButtonPushedFcn = createCallbackFcn(app, @CONVERTButtonPushed, true);
            app.CONVERTButton.Text = 'CONVERT ';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'Screenshot__154_-removebg-preview.png';
            app.R2Label_2 = uilabel(app.UIFigure);
            app.R2Label_2.Text = 'R2''';
            app.R1Label_3 = uilabel(app.UIFigure);
            app.R1Label_3.Text = 'R1''';
            app.R3Label_2 = uilabel(app.UIFigure);
            app.R3Label_2.Text = 'R3''';
            app.A = uieditfield(app.UIFigure, 'numeric');
            app.B = uieditfield(app.UIFigure, 'numeric');
            app.C = uieditfield(app.UIFigure, 'numeric');
            app.R1 = uieditfield(app.UIFigure, 'numeric');
            app.R2 = uieditfield(app.UIFigure, 'numeric');
            app.R3 = uieditfield(app.UIFigure, 'numeric');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'Capture-removebg-preview.png';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = 'Capture1-removebg-preview.png';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = 'Capture2-removebg-preview.png';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.ALabel = uilabel(app.UIFigure);
            app.ALabel.Text = 'A';
            app.BLabel = uilabel(app.UIFigure);
            app.BLabel.Text = 'B';
            app.CLabel = uilabel(app.UIFigure);
            app.CLabel.Text = 'C';
            app.NLabel = uilabel(app.UIFigure);
            app.NLabel.Text = 'N';
            app.ALabel_2 = uilabel(app.UIFigure);
            app.ALabel_2.Text = 'A';
            app.BLabel_2 = uilabel(app.UIFigure);
            app.BLabel_2.Text = 'B';
            app.CLabel_2 = uilabel(app.UIFigure);
            app.CLabel_2.Text = 'C';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StarDeltaConverter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end